classdef AnalyseMotionData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MarkerDataFolderButton     matlab.ui.control.Button
        GRFDataFolderButton        matlab.ui.control.Button
        OpenSimModelButton         matlab.ui.control.Button
        OpenSimModelPath           matlab.ui.control.EditField
        MarkerDataFolderPath       matlab.ui.control.EditField
        GRFDataFolderPath          matlab.ui.control.EditField
        ResultsFolderButton        matlab.ui.control.Button
        ResultsFolderPath          matlab.ui.control.EditField
        InverseKinematicsCheckBox  matlab.ui.control.CheckBox
        InverseDynamicsCheckBox    matlab.ui.control.CheckBox
        BodyKinematicsCheckBox     matlab.ui.control.CheckBox
        TextArea                   matlab.ui.control.TextArea
        ComponentofGaitAnalysisToolboxLabel  matlab.ui.control.Label
        DanielGordon2019Label      matlab.ui.control.Label
        AnalyseButton              matlab.ui.control.Button
        CMCCheckBox                matlab.ui.control.CheckBox
    end


    methods (Access = private)
    
        function base = getBestStartPath(app)
            
            possibilities = {app.MarkerDataFolderPath.Value, app.GRFDataFolderPath.Value, app.ResultsFolderPath.Value};
            base = [];
            for i=1:length(possibilities)
                if ~isempty(possibilities{i})
                    components = strsplit(possibilities{i}, filesep);
                    base = strjoin(components(1:end-1), filesep);
                    break;
                end
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSimModelButton
        function getModelFolder(app, event)
            base = getBestStartPath(app);
            try
                [file, path] = uigetfile([base filesep '*.osim'], 'Select OpenSim model file');
                app.OpenSimModelPath.Value = [path filesep file];
            catch 
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: MarkerDataFolderButton
        function getMarkerFolder(app, event)
            base = getBestStartPath(app);
            try
                app.MarkerDataFolderPath.Value = uigetdir(base, 'Select folder containing marker data');
            catch 
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: GRFDataFolderButton
        function getGRFFolder(app, event)
            base = getBestStartPath(app);
            try
                app.GRFDataFolderPath.Value = uigetdir(base, 'Select folder containing GRF data');
            catch 
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ResultsFolderButton
        function getResultsFolderPath(app, event)
            base = getBestStartPath(app);
            try
                app.ResultsFolderPath.Value = uigetdir(base, 'Select results folder');
            catch 
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: AnalyseButton
        function analyse(app, event)
            
            % Ensure we've been given a save directory.
            if isempty(app.ResultsFolderPath.Value)
                app.TextArea.Value = 'Analysis requires results directory.';
                return
            end
            
            % Ensure that we've been given a model.
            if isempty(app.OpenSimModelPath.Value)
                app.TextArea.Value = 'Analysis requires model file.';
                return
            end
            
            % Ensure we've been given marker data.
            if isempty(app.MarkerDataFolderPath)
                app.TextArea.Value = 'Analyses/analyses require marker data.';
                return
            end
            
            % Construct the OpenSim analyses array.
            analyses = {};
            if app.InverseKinematicsCheckBox.Value == 1
                analyses{end+1} = 'IK';
            end
            if app.InverseDynamicsCheckBox.Value == 1
                analyses{end+1} = 'ID';
            end
            if app.BodyKinematicsCheckBox.Value == 1
                analyses{end+1} = 'BK';
            end
            if app.CMCCheckBox.Value == 1
                analyses{end+1} = 'CMC';
            end
            
            % Run some checks.
            if isempty(analyses)
                app.TextArea.Value = 'Please select one or more analyses.';
                return
            end
            if (any(strcmp(analyses, 'ID')) || any(strcmp(analyses, 'CMC'))) && isempty(app.GRFDataFolderPath)
                app.TextArea.Value = 'GRF data required for both ID and CMC.';
                return
            end
            if (any(strcmp(analyses, 'ID')) || any(strcmp(analyses, 'BK')) || any(strcmp(analyses, 'CMC'))) && (~any(strcmp(analyses, 'IK')))
                app.TextArea.Value = 'IK is required for subsequent analyses.';
                return
            end
            
            % Provide some output.
            app.TextArea.Value = 'Analysing...';
            drawnow;
            
            % Perform analysis.
            trials = createTrials(app.OpenSimModelPath.Value, app.MarkerDataFolderPath.Value, app.ResultsFolderPath.Value, app.GRFDataFolderPath.Value);
            runBatchParallel(analyses, trials);
            app.TextArea.Value = 'Analysis complete.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MarkerDataFolderButton = uibutton(app.UIFigure, 'push');
            app.MarkerDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkerFolder, true);
            app.MarkerDataFolderButton.Text = 'Marker Data Folder';
            app.GRFDataFolderButton = uibutton(app.UIFigure, 'push');
            app.GRFDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFFolder, true);
            app.GRFDataFolderButton.Text = 'GRF Data Folder';
            app.OpenSimModelButton = uibutton(app.UIFigure, 'push');
            app.OpenSimModelButton.ButtonPushedFcn = createCallbackFcn(app, @getModelFolder, true);
            app.OpenSimModelButton.Text = 'OpenSim Model';
            app.OpenSimModelPath = uieditfield(app.UIFigure, 'text');
            app.MarkerDataFolderPath = uieditfield(app.UIFigure, 'text');
            app.GRFDataFolderPath = uieditfield(app.UIFigure, 'text');
            app.ResultsFolderButton = uibutton(app.UIFigure, 'push');
            app.ResultsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getResultsFolderPath, true);
            app.ResultsFolderButton.Text = 'Results Folder';
            app.ResultsFolderPath = uieditfield(app.UIFigure, 'text');
            app.InverseKinematicsCheckBox = uicheckbox(app.UIFigure);
            app.InverseKinematicsCheckBox.Text = 'Inverse Kinematics';
            app.InverseDynamicsCheckBox = uicheckbox(app.UIFigure);
            app.InverseDynamicsCheckBox.Text = 'Inverse Dynamics';
            app.BodyKinematicsCheckBox = uicheckbox(app.UIFigure);
            app.BodyKinematicsCheckBox.Text = 'Body Kinematics';
            app.TextArea = uitextarea(app.UIFigure);
            app.ComponentofGaitAnalysisToolboxLabel = uilabel(app.UIFigure);
            app.ComponentofGaitAnalysisToolboxLabel.Text = 'Component of Gait Analysis Toolbox  ';
            app.DanielGordon2019Label = uilabel(app.UIFigure);
            app.DanielGordon2019Label.Text = 'Daniel Gordon 2019';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @analyse, true);
            app.AnalyseButton.Text = 'Analyse';
            app.CMCCheckBox = uicheckbox(app.UIFigure);
            app.CMCCheckBox.Text = 'CMC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyseMotionData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end